classdef robotControllerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        DataTab                   matlab.ui.container.Tab
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.NumericEditField
        RollEditFieldLabel        matlab.ui.control.Label
        RollEditField             matlab.ui.control.NumericEditField
        PitchEditFieldLabel       matlab.ui.control.Label
        PitchEditField            matlab.ui.control.NumericEditField
        YawEditFieldLabel         matlab.ui.control.Label
        YawEditField              matlab.ui.control.NumericEditField
        JointanglesTextAreaLabel  matlab.ui.control.Label
        JointanglesTextArea       matlab.ui.control.TextArea
        StartButton               matlab.ui.control.Button
        IPEditFieldLabel          matlab.ui.control.Label
        IPEditField               matlab.ui.control.EditField
    end

    
    properties (Access = private)
        IP % Esp32 ip
        
        % End effector variables
        X_coord % X coord of end effector
        Y_coord % Y coord of end effector
        Z_coord % Z coord of end effector
        Roll % Rotation over X axis of end effector
        Pitch % Rotation over Y axis of end effector
        Yaw % Rotation over Z axis of end effector
        JointAngles % Description
        robot % Rigid body obj 
    end
    
    methods (Access = private)
        
        function t_matrix = getTransMatrix(app)
            % compute tranMatrix based on transvec and euler angles
            rot_matrix = eul2rotm([app.Yaw app.Pitch app.Roll]);
            t_matrix = [rot_matrix [app.X_coord;app.Y_coord;app.Z_coord];...
               0 0 0 1];
        end
        
        function pass = computeInverseKinematics(app,tform)
            
            % get inverse kinematics
            [jointAngles, cal_state] = robotInverseKinematics(app.robot,tform);
            disp(cal_state.Status)
            % Check if the position was reached
            if strcmp(cal_state.Status,'success')
                app.JointAngles = jointAngles;
                pass = true;
            else
                pass = false;
            end
        end
        
        function resetParameters(app,TMatrix)
            
            % Compute translational vector and euler angles from
            %  tranformation matrix
            trvec = tform2trvec(TMatrix);
            euler = rotm2eul(TMatrix(1:3,1:3),'XYZ');
            
            % Change input parameters
            app.XEditField.Value = trvec(1);
            app.X_coord = trvec(1);
            app.YEditField.Value = trvec(2);
            app.Y_coord = trvec(2);
            app.ZEditField.Value = trvec(3);
            app.Z_coord = trvec(3);
            app.RollEditField.Value = euler(1);
            app.Roll = euler(1);
            app.PitchEditField.Value = euler(2);
            app.Pitch = euler(2);
            app.YawEditField.Value = euler(3);
            app.Yaw = euler(3);
            app.IPEditField.Value = app.IP;
        end
        
        function sendHttpRequest(app, jointAngles)
            % send joint angles to robot controller 
            import matlab.net.*
            import matlab.net.http.*
            r = RequestMessage;
            %{
            disp(rad2deg(jointAngles(1)))
            disp(jointAngles(1))
            disp(jointAngles(2))
            disp(jointAngles(3))
            disp(jointAngles(4))
            disp(jointAngles(5))
            %}
            message = ['http://' app.IP '/?joint=1&value=' ...
                int2str(rad2deg(jointAngles(1))+90) '&joint=2&value=' ...
                int2str(rad2deg(jointAngles(2))+90) '&joint=3&value=' ...
                int2str(rad2deg(jointAngles(3))+90) '&joint=4&value=' ...
                int2str(rad2deg(jointAngles(4))+90) '&joint=5&value=' ...
                int2str(rad2deg(jointAngles(5))) '&=&'
                ];
            uri = URI(message);
%             disp(message)
            resp = send(r,uri);
        end
        
        function updateRobot(app)
            % Get robot current state
            currentJointAngles = app.JointAngles;
            current_TMatrix = getTransform(app.robot,...
                currentJointAngles,'gripper');
            % Compute inverse kinematics
            t_matrix = getTransMatrix(app);
            pass = computeInverseKinematics(app, t_matrix);
            %disp(t_matrix)
            if pass
                % If the desired position is reacheable
                %  compute joint space trajectory
                [~,jointConfigArray] = interpolationJointAngles(currentJointAngles,...
                    app.JointAngles,...
                    current_TMatrix,...
                    t_matrix);
                tjry_size = size(jointConfigArray);
                disp(tjry_size(2))
                % move joint angles 
                for i = 1:tjry_size(2)
                    jointAngles = jointConfigArray(:,i);
                    disp(rad2deg(jointAngles))
                    sendHttpRequest(app, jointAngles)
                end
            else
                % If the desired position cannot be reached,
                %  change parameters to last value
                f = errordlg("Can't reach position. Change parameters",'App Error');
                resetParameters(app, current_TMatrix);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Default parameters
            app.IP = '192.168.100.9';
            app.X_coord = 0;
            app.Y_coord = 0;
            app.Z_coord = 0.51;
            app.Roll=0;
            app.Pitch=0;
            app.Yaw=0;
            app.JointAngles=[0 0 0 0 0 0]';
            
            % Reset input values in app
            t_matrix = getTransMatrix(app);
            resetParameters(app,t_matrix);
            
            % Create robot model
            app.robot = createRobot();
            
            % Move robot to default position
            %updateRobot(app);
            sendHttpRequest(app, app.JointAngles)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % Get input values
            app.X_coord = app.XEditField.Value;
            app.Y_coord = app.YEditField.Value;
            app.Z_coord = app.ZEditField.Value;
            app.Roll = app.RollEditField.Value;
            app.Pitch = app.PitchEditField.Value;
            app.Yaw = app.YawEditField.Value;
            
            % Move robot
            updateRobot(app);
            
            % Print joint values
            app.JointanglesTextArea.Value = [int2str(app.JointAngles(1)) ...
                ',' int2str(rad2deg(app.JointAngles(2)))...
                ',' int2str(rad2deg(app.JointAngles(3)))...
                ',' int2str(rad2deg(app.JointAngles(4)))...
                ',' int2str(rad2deg(app.JointAngles(5)))];
        end

        % Value changing function: IPEditField
        function IPEditFieldValueChanging(app, event)
            app.IP = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.XEditFieldLabel = uilabel(app.DataTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.DataTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.DataTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.DataTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.DataTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.DataTab, 'numeric');
            app.RollEditFieldLabel = uilabel(app.DataTab);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.DataTab, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.DataTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.DataTab, 'numeric');
            app.YawEditFieldLabel = uilabel(app.DataTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.DataTab, 'numeric');
            app.JointanglesTextAreaLabel = uilabel(app.DataTab);
            app.JointanglesTextAreaLabel.Text = 'Joint angles';
            app.JointanglesTextArea = uitextarea(app.DataTab);
            app.StartButton = uibutton(app.DataTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.IPEditFieldLabel = uilabel(app.DataTab);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.DataTab, 'text');
            app.IPEditField.ValueChangingFcn = createCallbackFcn(app, @IPEditFieldValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotControllerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end